classdef MetOceanGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SubmitBtn                      matlab.ui.control.Button
        MetOceanGUILabel               matlab.ui.control.Label
        ASCCLogo                       matlab.ui.control.Image
        UMaineLogo                     matlab.ui.control.Image
        BlueBox                        matlab.ui.control.Label
        BkBtnConfirm                   matlab.ui.control.Button
        BkBtnVars                      matlab.ui.control.Button
        BkBtnExtractors                matlab.ui.control.Button
        CtnBtnVarsWarn                 matlab.ui.control.Label
        CtnBtnVars                     matlab.ui.control.Button
        CtnBtnExtractors               matlab.ui.control.Button
        ConfirmationText               matlab.ui.control.TextArea
        CtnBtnLatLonDate               matlab.ui.control.Button
        VariableSelection              matlab.ui.container.Panel
        VariableLabel                  matlab.ui.control.Label
        VariableTree                   matlab.ui.container.CheckBoxTree
        LocationAndDate                matlab.ui.container.Panel
        degreeseastfromgreenwichLabel  matlab.ui.control.Label
        degreesnorthfromequatorLabel   matlab.ui.control.Label
        EndDatePicker                  matlab.ui.control.DatePicker
        EndDateDatePickerLabel         matlab.ui.control.Label
        StartDatePicker                matlab.ui.control.DatePicker
        StartDateDatePickerLabel       matlab.ui.control.Label
        InputStartandenddateCheckBox   matlab.ui.control.CheckBox
        WestField                      matlab.ui.control.NumericEditField
        WestEditFieldLabel             matlab.ui.control.Label
        SouthField                     matlab.ui.control.NumericEditField
        SouthEditFieldLabel            matlab.ui.control.Label
        EastField                      matlab.ui.control.NumericEditField
        EastEditFieldLabel             matlab.ui.control.Label
        NorthEditFieldLabel            matlab.ui.control.Label
        NorthField                     matlab.ui.control.NumericEditField
        DownloadGridCheckBox           matlab.ui.control.CheckBox
        ExtractorSelection             matlab.ui.container.Panel
        OSCARCollectionButtonGroup     matlab.ui.container.ButtonGroup
        FinalButton                    matlab.ui.control.RadioButton
        InterimButton                  matlab.ui.control.RadioButton
        NRTButton                      matlab.ui.control.RadioButton
        NRELTemporalResolutionButtonGroup  matlab.ui.container.ButtonGroup
        HourButton                     matlab.ui.control.RadioButton
        HourlyButton                   matlab.ui.control.RadioButton
        SelectExtractorsListBox        matlab.ui.control.ListBox
        SelectExtractorsListBoxLabel   matlab.ui.control.Label
        Map                            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        latitude    = ncread('./bin/SSLatLon.nc', 'latitude')
        longitude   = ncread('./bin/SSLatLon.nc', 'longitude')
        seaSurfTemp = ncread('./bin/SSLatLon.nc', 'sst')
        eraInputs   = jsondecode(fileread('./bin/ERAInputs.json')).variable
        mhkitIn3    = jsondecode(fileread('./bin/MHKITInputs.json')).hr3
        mhkitIn1    = jsondecode(fileread('./bin/MHKITInputs.json')).hr1
        ndbcList    = readtable('./bin/NDBCBuoys.csv')
        buoyIDs     = {''}
        submitDone  = false
        sources     = {'ERA5', 'HYCOM', 'NREL', 'OSCAR', 'NDBC', 'WW3'}
        selected    = {}
        era5opts    = {}
        nrelopts    = {}
        ndbcopts    = {}
        oscropts    = {}
    end

    methods (Access = private)

        function UpdateMap(app)
            cla(app.Map);
            [Lat, Lon]  = meshgrid(app.latitude, app.longitude);
            contourf(app.Map, Lon, Lat, app.seaSurfTemp, 'HandleVisibility', 'off')
            hold(app.Map, 'on')

            if app.DownloadGridCheckBox.Value && app.EastField.Value ~= app.WestField.Value && app.NorthField.Value ~= app.SouthField.Value
                plot(app.Map, ...
                    [app.EastField.Value app.EastField.Value app.WestField.Value app.WestField.Value app.EastField.Value] + 360, ...
                    [app.NorthField.Value app.SouthField.Value app.SouthField.Value app.NorthField.Value app.NorthField.Value], ...
                    'r', 'LineWidth', 1.5, 'DisplayName', 'Selected Area')
                ylim(app.Map, [app.SouthField.Value - 6 app.NorthField.Value + 6])
                xlim(app.Map, [app.WestField.Value - 13 + 360 app.EastField.Value + 13 + 360])
            else
                scatter(app.Map, app.EastField.Value + 360, app.NorthField.Value, 'rd', 'filled', 'MarkerEdgeColor','#FFFFFF', 'DisplayName', 'Selected Area')
                ylim(app.Map, [app.NorthField.Value - 6 app.NorthField.Value + 6])
                xlim(app.Map, [app.EastField.Value - 13 + 360 app.EastField.Value + 13 + 360])
            end
            hold(app.Map, 'off')
            legend(app.Map)
            disableDefaultInteractivity(app.Map)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Do the Map
            [Lat, Lon]  = meshgrid(app.latitude, app.longitude);
            contourf(app.Map, Lon, Lat, app.seaSurfTemp, 'HandleVisibility', 'off')
            disableDefaultInteractivity(app.Map)
            set(app.LocationAndDate, 'Visible', 'on')
        end

        % Value changed function: DownloadGridCheckBox
        function DownloadGridCheckBoxValueChanged(app, event)
            if app.DownloadGridCheckBox.Value
                set([app.SouthField, app.SouthEditFieldLabel, app.WestField, app.WestEditFieldLabel], 'Visible', 'on')
                app.SouthField.Value = app.NorthField.Value;
                app.WestField.Value = app.EastField.Value;

            else
                set([app.SouthField, app.SouthEditFieldLabel, app.WestField, app.WestEditFieldLabel], 'Visible', 'off')
            end
        end

        % Value changed function: InputStartandenddateCheckBox
        function InputStartandenddateCheckBoxValueChanged(app, event)
            if app.InputStartandenddateCheckBox.Value
                set([app.StartDatePicker, app.StartDateDatePickerLabel, app.EndDatePicker, app.EndDateDatePickerLabel], 'Visible', 'on')
            else
                set([app.StartDatePicker, app.StartDateDatePickerLabel, app.EndDatePicker, app.EndDateDatePickerLabel], 'Visible', 'off')
            end
        end

        % Value changed function: NorthField
        function NorthFieldValueChanged(app, event)
            if app.NorthField.Value < app.SouthField.Value
                app.SouthField.Value = app.NorthField.Value - 0.01;
            end
            UpdateMap(app);
        end

        % Value changed function: EastField
        function EastFieldValueChanged(app, event)
            if app.EastField.Value < app.WestField.Value
                app.WestField.Value = app.EastField.Value - 0.01;
            end
            UpdateMap(app);
        end

        % Value changed function: SouthField
        function SouthFieldValueChanged(app, event)
            if app.NorthField.Value < app.SouthField.Value
                app.NorthField.Value = app.SouthField.Value;
            end
            UpdateMap(app);
        end

        % Value changed function: WestField
        function WestFieldValueChanged(app, event)
            if app.EastField.Value < app.WestField.Value
                app.EastField.Value = app.WestField.Value;
            end
            UpdateMap(app);
        end

        % Button pushed function: CtnBtnLatLonDate
        function CtnBtnLatLonDatePushed(app, event)
            UpdateMap(app);
            set([app.LocationAndDate, app.CtnBtnLatLonDate], 'Visible', 'off')
            set([app.ExtractorSelection, app.BkBtnExtractors, app.CtnBtnExtractors], 'Visible', 'on')
            if ~app.DownloadGridCheckBox.Value
                app.SouthField.Value = app.NorthField.Value;
                app.WestField.Value = app.EastField.Value;
            end
        end

        % Button pushed function: BkBtnExtractors
        function BkBtnExtractorsPushed2(app, event)
            set([app.LocationAndDate, app.CtnBtnLatLonDate], 'Visible', 'on')
            set([app.ExtractorSelection, app.CtnBtnExtractors, app.BkBtnExtractors], 'Visible', 'off')
        end

        % Button pushed function: CtnBtnExtractors
        function CtnBtnExtractorsPushed(app, event)
            app.selected = {};

            set([app.ExtractorSelection,app.CtnBtnExtractors, app.BkBtnExtractors], 'Visible', 'off')
            set([app.VariableSelection, app.BkBtnVars, app.CtnBtnVarsWarn], 'Visible', 'on')

            % build variable tree
            for v = 1:length(app.SelectExtractorsListBox.Value)
                e = app.SelectExtractorsListBox.Value{v};
                n = uitreenode(app.VariableTree, "Text", e);
                app.selected{end+1} = e;
                switch e
                    case 'ERA5'
                        for j = 1:length(app.eraInputs)
                            uitreenode(n, "Text", app.eraInputs{j});
                        end
                    case 'NREL'
                        if app.HourButton.Value
                            mhkitVars = app.mhkitIn3;
                            app.nrelopts = "3 hour";
                        else
                            mhkitVars = app.mhkitIn1;
                            app.nrelopts = "1 hour";
                        end
                        for j = 1:length(mhkitVars)
                            uitreenode(n, "Text", mhkitVars{j});
                        end
                    case 'NDBC'
                        % put the buoys on the map
                        linec = lines(10);
                        linsh = [repmat('o', 7,1);repmat('d', 3,1)];
                        buoyLat  = app.ndbcList{:, 10};
                        buoyLon  = app.ndbcList{:, 11};
                        N = (app.NorthField.Value + app.SouthField.Value)/2;
                        E = (app.WestField.Value + app.EastField.Value)/2;
                        diff = sqrt((N - buoyLat).^2 + (E - buoyLon).^2);
                        [~, bi] = sort(diff);
                        bi = bi(1:10);
                        buoyids = app.ndbcList{bi, 4};
                        app.buoyIDs = buoyids;
                        hold(app.Map, 'on')
                        for j = 1:length(buoyids)
                            scatter(app.Map, app.ndbcList{bi(j), 11} + 360, app.ndbcList{bi(j), 10}, 35, linec(j, :), 'filled', linsh(j), 'DisplayName', buoyids{j}, 'MarkerEdgeColor','#FFFFFF')
                            uitreenode(n, "Text", buoyids{j});
                        end
                    case 'OSCAR'
                        if app.FinalButton.Value
                            app.oscropts = 1;
                        elseif app.InterimButton.value
                            app.oscropts = 2;
                        else
                            app.oscropts = 3;
                        end

                end
            end
        end

        % Button pushed function: BkBtnVars
        function BkBtnVarsPushed(app, event)
            app.selected = {};
            UpdateMap(app);
            app.VariableTree.Children.delete;
            set([app.ExtractorSelection, app.CtnBtnExtractors, app.BkBtnExtractors], 'Visible', 'on')
            set([app.VariableSelection, app.CtnBtnVars, app.BkBtnVars, app.CtnBtnVarsWarn], 'Visible', 'off')
        end

        % Value changed function: SelectExtractorsListBox
        function SelectExtractorsListBoxValueChanged(app, event)
            value = app.SelectExtractorsListBox.Value;
            if any(contains(value, 'NREL'))
                set(app.NRELTemporalResolutionButtonGroup, 'Visible', 'on')
            else
                set(app.NRELTemporalResolutionButtonGroup, 'Visible', 'off')
            end
            if any(contains(value, 'OSCAR'))
                set(app.OSCARCollectionButtonGroup, 'Visible', 'on')
            else
                set(app.OSCARCollectionButtonGroup, 'Visible', 'off')
            end
        end

        % Value changed function: StartDatePicker
        function StartDatePickerValueChanged(app, event)
            startT = app.StartDatePicker.Value;
            endT = app.EndDatePicker.Value;
            if ~isempty(endT) && ~isempty(startT)
                if app.StartDatePicker.Value >= datetime('today')
                    app.StartDatePicker.Value = datetime('today') - caldays(1);
                end

                if startT >= endT
                    app.EndDatePicker.Value = app.StartDatePicker.Value + caldays(1);
                end
            end
        end

        % Value changed function: EndDatePicker
        function EndDatePickerValueChanged(app, event)
            startT = app.StartDatePicker.Value;
            endT = app.EndDatePicker.Value;
            if ~isempty(endT) && ~isempty(startT)
                if app.EndDatePicker.Value >= datetime('today')
                    app.EndDatePicker.Value = datetime('today');
                end

                if startT >= endT
                    app.StartDatePicker.Value = app.EndDatePicker.Value - caldays(1);
                end
            end
        end

        % Callback function: VariableTree
        function VariableTreeCheckedNodesChanged(app, event)
            checkedNodes = app.VariableTree.CheckedNodes;
            if ~isempty(checkedNodes)
                set(app.CtnBtnVarsWarn, 'Visible', 'off')
                set(app.CtnBtnVars, 'Visible', 'on')
            else
                set(app.CtnBtnVars, 'Visible', 'off')
                set(app.CtnBtnVarsWarn, 'Visible', 'on')
            end
        end

        % Button pushed function: CtnBtnVars
        function CtnBtnVarsButtonPushed(app, event)
            checkedNodes = app.VariableTree.CheckedNodes;
            set([app.VariableSelection, app.BkBtnVars, app.CtnBtnVars], 'Visible', 'off')
            set([app.BkBtnConfirm, app.ConfirmationText, app.SubmitBtn], 'Visible', 'on')
            displayText = cell(1, numel(checkedNodes));
            count = 1;
            for i = 1:length(checkedNodes)
                if ~isprop(checkedNodes(i).Parent, 'Text')
                    if contains(checkedNodes(i).Text, {'HYCOM', 'OSCAR', 'WW3'})
                        displayText{count} = ['[' checkedNodes(i).Text ']'];
                        count = count + 1;
                    end
                    continue;
                end
                switch checkedNodes(i).Parent.Text
                    case 'ERA5'
                        app.era5opts = [app.era5opts; checkedNodes(i).Text];
                    case 'NREL'
                        app.nrelopts = [app.nrelopts; checkedNodes(i).Text];
                    case 'NDBC'
                        app.ndbcopts = [app.ndbcopts; checkedNodes(i).Text];
                end
                displayText{count} = ['[' checkedNodes(i).Parent.Text '] ' checkedNodes(i).Text];
                count = count + 1;
            end
            app.ConfirmationText.Value = displayText(1:count-1);
        end

        % Button pushed function: BkBtnConfirm
        function BkBtnConfirmButtonPushed(app, event)
            set([app.VariableSelection, app.BkBtnVars, app.CtnBtnVars], 'Visible', 'on')
            set([app.BkBtnConfirm, app.ConfirmationText app.SubmitBtn], 'Visible', 'off')
        end

        % Button pushed function: SubmitBtn
        function SubmitBtnButtonPushed(app, event)
            if ~app.submitDone
                North = app.NorthField.Value;
                South = app.SouthField.Value;
                East = app.EastField.Value;
                West = app.WestField.Value;
                startTime = app.StartDatePicker.Value;
                endTime = app.EndDatePicker.Value;
                Space = 0.25;
                opts.era5 = app.era5opts;
                opts.nrel = app.nrelopts;
                opts.ndbc = app.ndbcopts;
                opts.oscr = app.oscropts;

                save('./temp/NESW.mat', 'North', 'South', 'East', 'West', 'opts');

                % add latitude and longitude grid if MHKIT is selected
                if any(contains(app.selected, 'NREL'))
                    Latitudes  = South:Space/2:North;
                    Longitudes = West:Space:East;
                    combos     = combvec(Latitudes, Longitudes);
                    latitudes  = combos(1,:)';
                    longitudes = combos(2,:)';

                    save('./temp/NESW.mat', '-append', 'latitudes', 'longitudes')
                end

                % add the times to the mat file if they have been set
                varEnd = '';
                if ~isempty(endTime)
                    varEnd = ',startTime,endTime';
                end

                % call all the extractors
                for i = 1:length(app.selected)
                    % initiate the for calling a new instance of MATLAB
                    cmdstr = ['matlab -nosplash ' sprintf(' -sd %s/source', pwd) ' -r load(''../temp/NESW.mat'');'];

                    % save the time range if it is set, but check it's within bounds first.
                    if ~isempty(endTime)
                        [startTime, endTime] = checkTimes(startTime, endTime, 'ERA5');
                        save('./temp/NESW.mat', '-append', 'startTime', 'endTime')
                    end

                    % add each extractor to the string with their various input arguements
                    switch app.selected{i}
                        case 'ERA5'
                            extPro = sprintf('ExtractERA5(North,East,South,West,opts%s);', varEnd);
                        case 'HYCOM'
                            extPro = sprintf('ExtractHYCOM(North,East,South,West%s);', varEnd);
                        case 'NDBC'
                            extPro = sprintf('ExtractNDBC(North,East,opts%s);', varEnd);
                        case 'NREL'
                            extPro = sprintf('ExtractMHKIT(latitudes,longitudes,opts%s);', varEnd);
                        case 'OSCAR'
                            extPro = sprintf('ProcessOSCAR(North,East,opts%s);', varEnd);
                        case 'WW3'
                            extPro = sprintf('ExtractWW3(North,East,South,West%s);', varEnd);
                    end

                    % add the extractor and processor to the string
                    cmdstr = strcat(cmdstr, extPro);

                    % run the system command and open each extractor in a new matlab window
                    system([cmdstr ' &']);
                end
            end
            app.submitDone = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Map = uiaxes(app.UIFigure);
            app.Map.XTick = [];
            app.Map.YTick = [];
            app.ExtractorSelection = uipanel(app.UIFigure);
            app.ExtractorSelection.Visible = 'off';
            app.SelectExtractorsListBoxLabel = uilabel(app.ExtractorSelection);
            app.SelectExtractorsListBoxLabel.Text = 'Select Extractors';
            app.SelectExtractorsListBox = uilistbox(app.ExtractorSelection);
            app.SelectExtractorsListBox.Items = {'ERA5', 'HYCOM', 'OSCAR', 'NREL', 'NDBC', 'WW3'};
            app.SelectExtractorsListBox.Multiselect = 'on';
            app.SelectExtractorsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectExtractorsListBoxValueChanged, true);
            app.SelectExtractorsListBox.Tooltip = {'ERA5 - Wind and wave data'; 'HYCOM - ocean surface current data'; 'NREL - wave hindcast data'; 'NOAA - ocean buoy data'; 'OSCAR - NASA surface currents'; 'WW3 - WaveWatchIII'};
            app.SelectExtractorsListBox.Value = {'ERA5'};
            app.NRELTemporalResolutionButtonGroup = uibuttongroup(app.ExtractorSelection);
            app.NRELTemporalResolutionButtonGroup.Title = 'NREL Temporal Resolution';
            app.NRELTemporalResolutionButtonGroup.Visible = 'off';
            app.HourlyButton = uiradiobutton(app.NRELTemporalResolutionButtonGroup);
            app.HourlyButton.Text = 'Hourly';
            app.HourButton = uiradiobutton(app.NRELTemporalResolutionButtonGroup);
            app.HourButton.Text = '3 Hour';
            app.HourButton.Value = true;
            app.OSCARCollectionButtonGroup = uibuttongroup(app.ExtractorSelection);
            app.OSCARCollectionButtonGroup.Title = 'OSCAR Collection';
            app.OSCARCollectionButtonGroup.Visible = 'off';
            app.NRTButton = uiradiobutton(app.OSCARCollectionButtonGroup);
            app.NRTButton.Text = 'Near-Real-Time (1 day lag)';
            app.InterimButton = uiradiobutton(app.OSCARCollectionButtonGroup);
            app.InterimButton.Text = 'Interim (1 month lag)';
            app.FinalButton = uiradiobutton(app.OSCARCollectionButtonGroup);
            app.FinalButton.Text = 'Final (1 year lag)';
            app.FinalButton.Value = true;
            app.LocationAndDate = uipanel(app.UIFigure);
            app.LocationAndDate.Visible = 'off';
            app.DownloadGridCheckBox = uicheckbox(app.LocationAndDate);
            app.DownloadGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownloadGridCheckBoxValueChanged, true);
            app.DownloadGridCheckBox.Text = 'Download Grid?';
            app.NorthField = uieditfield(app.LocationAndDate, 'numeric');
            app.NorthField.ValueChangedFcn = createCallbackFcn(app, @NorthFieldValueChanged, true);
            app.NorthField.Value = 43.1336;
            app.NorthEditFieldLabel = uilabel(app.LocationAndDate);
            app.NorthEditFieldLabel.Text = 'North';
            app.EastEditFieldLabel = uilabel(app.LocationAndDate);
            app.EastEditFieldLabel.Text = 'East';
            app.EastField = uieditfield(app.LocationAndDate, 'numeric');
            app.EastField.ValueChangedFcn = createCallbackFcn(app, @EastFieldValueChanged, true);
            app.EastField.Value = -68.3266;
            app.SouthEditFieldLabel = uilabel(app.LocationAndDate);
            app.SouthEditFieldLabel.Visible = 'off';
            app.SouthEditFieldLabel.Text = 'South';
            app.SouthField = uieditfield(app.LocationAndDate, 'numeric');
            app.SouthField.ValueChangedFcn = createCallbackFcn(app, @SouthFieldValueChanged, true);
            app.SouthField.Visible = 'off';
            app.WestEditFieldLabel = uilabel(app.LocationAndDate);
            app.WestEditFieldLabel.Visible = 'off';
            app.WestEditFieldLabel.Text = 'West';
            app.WestField = uieditfield(app.LocationAndDate, 'numeric');
            app.WestField.ValueChangedFcn = createCallbackFcn(app, @WestFieldValueChanged, true);
            app.WestField.Visible = 'off';
            app.InputStartandenddateCheckBox = uicheckbox(app.LocationAndDate);
            app.InputStartandenddateCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputStartandenddateCheckBoxValueChanged, true);
            app.InputStartandenddateCheckBox.Text = 'Input Start and end date?';
            app.StartDateDatePickerLabel = uilabel(app.LocationAndDate);
            app.StartDateDatePickerLabel.Visible = 'off';
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDatePicker = uidatepicker(app.LocationAndDate);
            app.StartDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDatePickerValueChanged, true);
            app.StartDatePicker.Visible = 'off';
            app.EndDateDatePickerLabel = uilabel(app.LocationAndDate);
            app.EndDateDatePickerLabel.Visible = 'off';
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDatePicker = uidatepicker(app.LocationAndDate);
            app.EndDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDatePickerValueChanged, true);
            app.EndDatePicker.Visible = 'off';
            app.degreesnorthfromequatorLabel = uilabel(app.LocationAndDate);
            app.degreesnorthfromequatorLabel.Text = '(degrees north from equator)';
            app.degreeseastfromgreenwichLabel = uilabel(app.LocationAndDate);
            app.degreeseastfromgreenwichLabel.Text = '(degrees east from greenwich)';
            app.VariableSelection = uipanel(app.UIFigure);
            app.VariableSelection.Visible = 'off';
            app.VariableTree = uitree(app.VariableSelection, 'checkbox');
            % Assign Checked Nodes
            app.VariableTree.CheckedNodesChangedFcn = createCallbackFcn(app, @VariableTreeCheckedNodesChanged, true);
            app.VariableLabel = uilabel(app.VariableSelection);
            app.VariableLabel.Text = 'Select Variables';
            app.CtnBtnLatLonDate = uibutton(app.UIFigure, 'push');
            app.CtnBtnLatLonDate.ButtonPushedFcn = createCallbackFcn(app, @CtnBtnLatLonDatePushed, true);
            app.CtnBtnLatLonDate.Text = 'Continue';
            app.ConfirmationText = uitextarea(app.UIFigure);
            app.ConfirmationText.Editable = 'off';
            app.ConfirmationText.Visible = 'off';
            app.CtnBtnExtractors = uibutton(app.UIFigure, 'push');
            app.CtnBtnExtractors.ButtonPushedFcn = createCallbackFcn(app, @CtnBtnExtractorsPushed, true);
            app.CtnBtnExtractors.Visible = 'off';
            app.CtnBtnExtractors.Text = 'Continue';
            app.CtnBtnVars = uibutton(app.UIFigure, 'push');
            app.CtnBtnVars.ButtonPushedFcn = createCallbackFcn(app, @CtnBtnVarsButtonPushed, true);
            app.CtnBtnVars.Visible = 'off';
            app.CtnBtnVars.Text = 'Continue';
            app.CtnBtnVarsWarn = uilabel(app.UIFigure);
            app.CtnBtnVarsWarn.Visible = 'off';
            app.CtnBtnVarsWarn.Text = 'Select variables to continue...';
            app.BkBtnExtractors = uibutton(app.UIFigure, 'push');
            app.BkBtnExtractors.ButtonPushedFcn = createCallbackFcn(app, @BkBtnExtractorsPushed2, true);
            app.BkBtnExtractors.Visible = 'off';
            app.BkBtnExtractors.Text = 'Back';
            app.BkBtnVars = uibutton(app.UIFigure, 'push');
            app.BkBtnVars.ButtonPushedFcn = createCallbackFcn(app, @BkBtnVarsPushed, true);
            app.BkBtnVars.Visible = 'off';
            app.BkBtnVars.Text = 'Back';
            app.BkBtnConfirm = uibutton(app.UIFigure, 'push');
            app.BkBtnConfirm.ButtonPushedFcn = createCallbackFcn(app, @BkBtnConfirmButtonPushed, true);
            app.BkBtnConfirm.Visible = 'off';
            app.BkBtnConfirm.Text = 'Back';
            app.BlueBox = uilabel(app.UIFigure);
            app.BlueBox.Text = '';
            app.UMaineLogo = uiimage(app.UIFigure);
            app.UMaineLogo.ImageSource = fullfile(pathToMLAPP, 'images', 'UMaine_fullcrest_logo4c_reverse.png');
            app.ASCCLogo = uiimage(app.UIFigure);
            app.ASCCLogo.ImageSource = fullfile(pathToMLAPP, 'images', 'umaineascc.png');
            app.MetOceanGUILabel = uilabel(app.UIFigure);
            app.MetOceanGUILabel.Text = 'MetOcean GUI';
            app.SubmitBtn = uibutton(app.UIFigure, 'push');
            app.SubmitBtn.ButtonPushedFcn = createCallbackFcn(app, @SubmitBtnButtonPushed, true);
            app.SubmitBtn.Visible = 'off';
            app.SubmitBtn.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetOceanGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end